classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure             matlab.ui.Figure
        EvaluatePlotsButton  matlab.ui.control.Button
        deltaSlider          matlab.ui.control.Slider
        DampingRatioLabel    matlab.ui.control.Label
        omega_nSlider        matlab.ui.control.Slider
        w_nLabel             matlab.ui.control.Label
        KSlider              matlab.ui.control.Slider
        KSliderLabel         matlab.ui.control.Label
        SecondOrderTFLabel   matlab.ui.control.Label
        UIAxes3              matlab.ui.control.UIAxes
        UIAxes2              matlab.ui.control.UIAxes
        UIAxes               matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % THIS FUNCTION IS EDITABLE
            app.UIFigure.Color = '#E6E6FA';
            app.SecondOrderTFLabel.Interpreter = 'latex';
            app.SecondOrderTFLabel.Text = 'Second Order Tranfer Function: \frac{$K\omega_{n}^{2}$}{$s^{2} + 2\delta\omega_{n}s + \omega_{n}^{2}$}';
            
            K = app.KSlider.Value;
            w_n = app.omega_nSlider.Value;
            delta = app.deltaSlider.Value;
            
            [num, den] = ord2(w_n, delta);
            sys = tf(num*K*w_n*w_n, den);
            
            bode(app.UIAxes, sys);
            pzplot(app.UIAxes2, sys);
            step(app.UIAxes3, sys);
        end

        % Button pushed function: EvaluatePlotsButton
        function EvaluatePlotsButtonPushed(app, event)
            % THIS FUNCTION IS EDITABLE
            K = app.KSlider.Value;
            w_n = app.omega_nSlider.Value;
            delta = app.deltaSlider.Value;
            [num, den] = ord2(w_n, delta);
            sys = tf(num*K*w_n*w_n, den);
            bode(app.UIAxes, sys);
            pzplot(app.UIAxes2, sys);
            step(app.UIAxes3, sys);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Bode')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'PZ Diagram')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            zlabel(app.UIAxes2, 'Z')
            app.UIAxes3 = uiaxes(app.UIFigure);
            title(app.UIAxes3, 'Step Response')
            xlabel(app.UIAxes3, 'X')
            ylabel(app.UIAxes3, 'Y')
            zlabel(app.UIAxes3, 'Z')
            app.SecondOrderTFLabel = uilabel(app.UIFigure);
            app.SecondOrderTFLabel.Text = 'SecondOrderTF';
            app.KSliderLabel = uilabel(app.UIFigure);
            app.KSliderLabel.Interpreter = 'latex';
            app.KSliderLabel.Text = 'K';
            app.KSlider = uislider(app.UIFigure);
            app.KSlider.Limits = [0 5];
            app.w_nLabel = uilabel(app.UIFigure);
            app.w_nLabel.Interpreter = 'latex';
            app.w_nLabel.Text = '$\omega_n$';
            app.omega_nSlider = uislider(app.UIFigure);
            app.DampingRatioLabel = uilabel(app.UIFigure);
            app.DampingRatioLabel.Interpreter = 'latex';
            app.DampingRatioLabel.Text = '$\delta$';
            app.deltaSlider = uislider(app.UIFigure);
            app.deltaSlider.Limits = [0 1];
            app.EvaluatePlotsButton = uibutton(app.UIFigure, 'push');
            app.EvaluatePlotsButton.ButtonPushedFcn = createCallbackFcn(app, @EvaluatePlotsButtonPushed, true);
            app.EvaluatePlotsButton.Text = 'Evaluate Plots';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end